classdef ui_yw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ImageAxes    matlab.ui.control.UIAxes
        GreenAxes    matlab.ui.control.UIAxes
        BlueAxes     matlab.ui.control.UIAxes
        LoadButton   matlab.ui.control.Button
        RedAxes      matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Label_8      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_9      matlab.ui.control.Label
        EditField_4  matlab.ui.control.EditField
        UIAxes       matlab.ui.control.UIAxes
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes_3     matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                    im = imresize(im,[1200,960]);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);
            
            % Set y axes limits based on largest bin count
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            % Update the image and histograms
            %updateimage(app, 'peppers.png');
            global i;
            i=0;
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global img;
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               img = imread(fname);
               img = imresize(img,[1200,960]);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global img;
            global i;
            global label;
            a=img;
            load('flowernet.mat');
            b=imresize(a,[227,227]);
            [label,score]= classify(flowernet,b);
            set(app.EditField_4,'Value',num2str(max(score)));
            set(app.EditField_3,'Value',char(label));
            text="识别结果为："+char(label);
            audio=call4api(text);
            sound(audio);
        end

        % Callback function
        function Button_3Pushed(app, event)
            warndlg('提示','模型加载完毕');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global label;
            global img;
            gray = rgb2gray(img);
            red=img(:,:,1);
            green=img(:,:,2);
            blue=img(:,:,3);
            if(char(label)=="乒乓菊")
                temp=red-green;
                flower1=imbinarize(temp);
                temp=blue-green;
                flower2=imbinarize(temp);
                flower=(flower1+flower2);
                temp=green-red;
                leaf=imbinarize(temp);
                %由于有些叶子比较暗，加上一些较暗的区域
                temp=zeros(size(gray));
                for i=1:size(gray,1)
                    for j=1:size(gray,2)
                        if(gray(i,j)<15)
                            temp(i,j)=1;
                        end
                    end
                end
                temp=logical(temp);
                leaf=(leaf+temp);
                %形态学处理，得到闭合的区域，还有些内部是白的填充一下
                %孔洞填充
                se = strel('disk',3);
                closef = imclose(flower,se);
                flower=imfill(flower,'holes');
                leaf=imfill(leaf,'holes');
                leaf=logical(leaf-closef.*leaf);
                imshow(uint8(closef).*img ,'Parent',app.UIAxes);
                colors1=imfuse(uint8(leaf).*img,uint8(closef).*img,'ColorChannels',[1 0 2]);
                result = imadd(colors1,img);
                imshow(colors1,'Parent',app.UIAxes_2);
                imshow(result,'Parent',app.UIAxes_3);
            end
            if(char(label)=="蓝绣球")
                temp=blue-green;
                flower=imbinarize(temp);
                temp=green-red;
                leaf=imbinarize(temp);
                temp=zeros(size(gray));
                temp=logical(temp);
                leaf=(leaf+temp);
                flower=logical(flower+temp);
                %形态学操作
                se = strel('disk',9);
                closef = imclose(flower,se);
                closel = imclose(leaf,se);
                %孔洞填充
                flower1=logical(imfill(closef,'holes'));
                leaf1=imfill(closel,'holes');
                leaf1=logical(leaf1-flower1.*leaf1);
                leaf11=uint8(leaf1).*img;
                %发现叶子部分残留一些白色的，通过将三通道加起来取均值减掉大的部分，即白色部分。
                temp=zeros(size(gray));
                for i=1:size(gray,1)
                    for j=1:size(gray,2)
                        %由于叶子暗色的部分不明显所以加点亮度使得显示更清晰
                        if(leaf11(i,j)~=0)
                            leaf11(i,j)=leaf11(i,j)+100;
                        end
                        if(leaf11(i,j,3)>200)
                            leaf11(i,j,:)=0;
                        end
                    end
                end
                colors1=imfuse(leaf11,uint8(flower1).*img,'ColorChannels',[1 2 0]);
                result = imadd(colors1,img);
                imshow(result,'Parent',app.UIAxes_3);
                imshow(uint8(flower1).*img,'Parent',app.UIAxes);
                imshow(colors1,'Parent',app.UIAxes_2);
            end
            if(char(label)=="香水百合")
                temp=blue-green;
                flower1=imbinarize(temp);
                temp=red-green;
                flower2=imbinarize(temp);
                flower=(flower1+flower2);
                %清除花盆红色部分
                
                %同样涉及到花边缘白色的问题
                temp=zeros(size(gray));
                for i=1:size(gray,1)
                    for j=1:size(gray,2)
                        if(gray(i,j)>254)
                            temp(i,j)=1;
                        end
                    end
                end
                temp=logical(temp);
                flower=temp+flower;
                temp=green-red;
                leaf1=imbinarize(temp);
                temp=green-blue;
                leaf2=imbinarize(temp);
                leaf=logical(leaf1+leaf2);
                %形态学操作
                se = strel('disk',11);
                se1 = strel('disk',2);
                closef = imclose(flower,se);
                closel = imclose(leaf,se);
                %孔洞填充
                flower1=logical(imfill(closef,'holes'));
                leaf1=imfill(closel,'holes');
                %加点白色的
                flower1 = imdilate(flower1,se1);
                leaf1=logical(leaf1-flower1.*leaf1);
                flower11=uint8(flower1).*img;
                leaf11=uint8(leaf1).*img;
                %由于有些叶子比较暗，加上一些较暗的区域并去掉由于闭运算得到的白色部分
                temp=zeros(size(gray));
                for i=1:size(gray,1)
                    for j=1:size(gray,2)
                        if(leaf11(i,j,1)>220)
                            leaf11(i,j)=0;
                        end
                        if(gray(i,j)<10)
                            leaf11(i,j)=100;
                        end
                    end
                end
                
                %画图
                colors1=imfuse(leaf11,flower11,'ColorChannels',[1 0 2]);
                result = imadd(colors1,img);
                imshow(result,'Parent',app.UIAxes_3);
                imshow(flower11,'Parent',app.UIAxes);
                imshow(colors1,'Parent',app.UIAxes_2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '加载图片';
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '花/叶分割与识别';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '袁尉 2017141444046';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '自动识别种类';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始分割';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '识别结果';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '暂无';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '准确率';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = '暂无';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '花的分割')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '花叶分割区域显示')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '标注后的原图')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_yw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end